classdef mainApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        HMACPanel              matlab.ui.container.Panel
        HMACKeyEditField       matlab.ui.control.EditField
        HMACKeyEditFieldLabel  matlab.ui.control.Label
        DESPanel               matlab.ui.container.Panel
        DESKeyTextArea         matlab.ui.control.TextArea
        DESKeyEditField        matlab.ui.control.EditField
        DESKeyLabel            matlab.ui.control.Label
        OTPPanel               matlab.ui.container.Panel
        OTPKeyEditField        matlab.ui.control.TextArea
        Label                  matlab.ui.control.Label
        OneTimePadKeyGeneratedRandomlyLabel  matlab.ui.control.Label
        ExitButton             matlab.ui.control.Button
        MainMenuButton         matlab.ui.control.Button
        MainMenuPanel          matlab.ui.container.Panel
        XORPanel               matlab.ui.container.Panel
        XORKeyEditField        matlab.ui.control.EditField
        XORKeyLabel            matlab.ui.control.Label
        CaesarPanel            matlab.ui.container.Panel
        ShiftEditField         matlab.ui.control.NumericEditField
        ShiftEditFieldLabel    matlab.ui.control.Label
    end

    properties (Access = public)
        UI struct                     % dynamic UI handles per panel
    
        currentText char = ''
        currentImage = []
        outputText char = ''
        outputImage = []
        currentKey char = ''
        currentModule char = ''

        isInputImage logical = false;   % default = text mode
    end

    
    methods (Access = public)
        %% Panel switching & utils
        function switchPanel(app, panelName)
            % Hide all panel-type properties and show the selected one
            props = properties(app);
            for i = 1:numel(props)
                n = props{i};
                try
                    if isa(app.(n),'matlab.ui.container.Panel')
                        app.(n).Visible = 'off';
                    end
                catch
                end
            end
            % show requested panel
            app.(panelName).Visible = 'on';
            app.currentModule = panelName;

            % reset module state when switching in
            resetModule(app,panelName);
            app.isInputImage=false;
            if(~strcmp(panelName,'MainMenuPanel'))
                app.UI.(panelName).TextRadio.Value = true;
                switchInputMode(app,'Text',panelName) %Text mode by default
            end
            updateStatus(app, "Ready");
        end

        %% UI creation helpers (call at startup)
        function addPanels(app)
            

            uilabel(app.MainMenuPanel, 'Text','Select module:', 'Position',[20 340 200 22]);
            uibutton(app.MainMenuPanel, 'Text','Caesar Cipher', 'Position',[20 300 200 30], ...
                'ButtonPushedFcn', @(~,~)switchPanel(app,'CaesarPanel'));
            uibutton(app.MainMenuPanel, 'Text','One Time Pad', 'Position',[240 300 200 30], ...
                'ButtonPushedFcn', @(~,~)switchPanel(app,'OTPPanel'));
            uibutton(app.MainMenuPanel, 'Text','XOR (Text & Image)', 'Position',[460 300 200 30], ...
                'ButtonPushedFcn', @(~,~)switchPanel(app,'XORPanel'));
            uibutton(app.MainMenuPanel, 'Text','DES Block Cipher', 'Position',[20 250 200 30], ...
                'ButtonPushedFcn', @(~,~)switchPanel(app,'DESPanel'));
            uibutton(app.MainMenuPanel, 'Text','HMAC (SHA256)', 'Position',[240 250 200 30], ...
                 'ButtonPushedFcn', @(~,~)switchPanel(app,'HMACPanel'));
            % uibutton(app.MainMenuPanel, 'Text','XOR (Text & Image)', 'Position',[460 250 200 30], ...
            %     'ButtonPushedFcn', @(~,~)switchPanel(app,'XORPanel'));
        end 

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % add project subfolders to path so handlers/crypt/io/preview/components are visible
            root = fileparts(mfilename('fullpath'));
            addpath(fullfile(root,'components'));
            addpath(fullfile(root,'crypt'));
            addpath(fullfile(root,'crypt','des'));
            addpath(fullfile(root,'handlers'));
            addpath(fullfile(root,'io'));
            addpath(fullfile(root,'io','encoding'));
        
            % initialize namespace
            app.UI = struct();


            % Create static panels and main menu
            addPanels(app);

            % create reusable UI inside panels
            addReusableIOComponents(app,'CaesarPanel', false); % text only
            addReusableIOComponents(app,'OTPPanel',false);
            addReusableIOComponents(app,'XORPanel', true);     % text and image
            addReusableIOComponents(app,'DESPanel', true);     % text and image
            addReusableIOComponents(app,'HMACPanel',false);

            % show Main Menu first
            switchPanel(app,'MainMenuPanel');

        end

        % Button pushed function: MainMenuButton
        function MainMenuButtonPushed(app, event)
            switchPanel(app, 'MainMenuPanel')
        end

        % Value changed function: XORKeyEditField
        function XORKeyEditFieldValueChanged(app, event)
            value = app.XORKeyEditField.Value;
            app.currentKey = value;
        end

        % Button pushed function: ExitButton
        function ExitButtonPushed(app, event)
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.CaesarPanel = uipanel(app.UIFigure);
            app.CaesarPanel.Title = 'Caesar';
            app.CaesarPanel.Visible = 'off';
            app.ShiftEditFieldLabel = uilabel(app.CaesarPanel);
            app.ShiftEditFieldLabel.Text = 'Shift';
            app.ShiftEditField = uieditfield(app.CaesarPanel, 'numeric');
            app.ShiftEditField.Limits = [1 25];
            app.ShiftEditField.ValueDisplayFormat = '%.0f';
            app.ShiftEditField.Value = 3;
            app.XORPanel = uipanel(app.UIFigure);
            app.XORPanel.Title = 'XOR';
            app.XORPanel.Visible = 'off';
            app.XORKeyLabel = uilabel(app.XORPanel);
            app.XORKeyLabel.Text = 'XOR Key';
            app.XORKeyEditField = uieditfield(app.XORPanel, 'text');
            app.XORKeyEditField.ValueChangedFcn = createCallbackFcn(app, @XORKeyEditFieldValueChanged, true);
            app.MainMenuPanel = uipanel(app.UIFigure);
            app.MainMenuPanel.Title = 'Main Menu';
            app.MainMenuPanel.Visible = 'off';
            app.MainMenuButton = uibutton(app.UIFigure, 'push');
            app.MainMenuButton.ButtonPushedFcn = createCallbackFcn(app, @MainMenuButtonPushed, true);
            app.MainMenuButton.Text = 'Main Menu';
            app.ExitButton = uibutton(app.UIFigure, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.ExitButton.Text = 'Exit';
            app.OTPPanel = uipanel(app.UIFigure);
            app.OTPPanel.Title = 'OTP';
            app.OTPPanel.Visible = 'off';
            app.OneTimePadKeyGeneratedRandomlyLabel = uilabel(app.OTPPanel);
            app.OneTimePadKeyGeneratedRandomlyLabel.Text = 'One Time Pad Key (Generated Randomly)';
            app.Label = uilabel(app.OTPPanel);
            app.Label.Text = '';
            app.OTPKeyEditField = uitextarea(app.OTPPanel);
            app.OTPKeyEditField.Editable = 'off';
            app.DESPanel = uipanel(app.UIFigure);
            app.DESPanel.Title = 'DES';
            app.DESPanel.Visible = 'off';
            app.DESKeyLabel = uilabel(app.DESPanel);
            app.DESKeyLabel.Text = 'DES Key';
            app.DESKeyEditField = uieditfield(app.DESPanel, 'text');
            app.DESKeyEditField.CharacterLimits = [0 7];
            app.DESKeyEditField.Placeholder = '(0 or 7 chars only)';
            app.DESKeyTextArea = uitextarea(app.DESPanel);
            app.DESKeyTextArea.Editable = 'off';
            app.DESKeyTextArea.Placeholder = ' 8×8 bit key matrix                 (last col = parity bits)';
            app.HMACPanel = uipanel(app.UIFigure);
            app.HMACPanel.Title = 'HMAC';
            app.HMACPanel.Visible = 'off';
            app.HMACKeyEditFieldLabel = uilabel(app.HMACPanel);
            app.HMACKeyEditFieldLabel.Text = 'HMAC Key';
            app.HMACKeyEditField = uieditfield(app.HMACPanel, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = mainApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end